classdef Differentiator_And_ODE_Solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure  matlab.ui.Figure
        UIAxes_diff                  matlab.ui.control.UIAxes
        DyEditFieldLabel             matlab.ui.control.Label
        DyEditField                  matlab.ui.control.EditField
        yEditFieldLabel              matlab.ui.control.Label
        ynEditField                  matlab.ui.control.EditField
        SolutionMethodDropDownLabel  matlab.ui.control.Label
        SolutionMethodDropDown       matlab.ui.control.DropDown
        SolveODEButton               matlab.ui.control.Button
        ClearButton1                 matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        InitialyValueEditField       matlab.ui.control.EditField
        InitialDyValueEditField      matlab.ui.control.EditField
        DynEditField                 matlab.ui.control.EditField
        SecondOrderCheckBox          matlab.ui.control.CheckBox
        Label_2                      matlab.ui.control.Label
        DyLabel                      matlab.ui.control.Label
        ODESOLVERLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        DifferentiateButton          matlab.ui.control.Button
        DifferentiatorLabel          matlab.ui.control.Label
        DDLabel                      matlab.ui.control.Label
        DD                           matlab.ui.control.DropDown
        EquatLabel                   matlab.ui.control.Label
        Equat                        matlab.ui.control.EditField
        Example6y5D2y4yLabel         matlab.ui.control.Label
        Examplesinxx2etcLabel        matlab.ui.control.Label
        ClearButton2                 matlab.ui.control.Button
        ClearButton3                 matlab.ui.control.Button
        OpenSimulinkLabel            matlab.ui.control.Label
        SimulinkButton               matlab.ui.control.Button
        Label_3                      matlab.ui.control.Label
        ClearAllButton               matlab.ui.control.Button
        CheckitbeforeenteringLabel   matlab.ui.control.Label
        secondorderequationLabel     matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes_diff, app.SolutionMethodDropDown.Value);
            xlabel(app.UIAxes_diff,'t');
            xlim(app.UIAxes_diff, [0 5]);
            ylabel(app.UIAxes_diff,'y');
        end

        % Button pushed function: SolveODEButton
        function SolveODEButtonPushed(app, event)
            if(string(app.SolutionMethodDropDown.Value) =='Explicit')
                
                
                % Solution
                equation=strjoin({'Dy =', app.DyEditField.Value});
                
                init1 = strjoin({'y(', app.ynEditField.Value,') =', app.InitialyValueEditField.Value});
                
                if (app.SecondOrderCheckBox.Value == 0)
                    init = init1;                    
                else
                    init2 = strjoin({'Dy(', app.DynEditField.Value,') =', app.InitialDyValueEditField.Value});
                    init=strjoin({init1, ', ', init2});
                end
                y=dsolve(equation,init);
                ym=matlabFunction(y);
                t=0:0.01:10;
                plot(app.UIAxes_diff,t,ym(t));
            
            elseif(string(app.SolutionMethodDropDown.Value) =='Eulers')
                % Solution
                str = strjoin({'@(t, y) ',app.DyEditField.Value});
                init=app.ynEditField.Value;
                h=0.01;
                t=0:h:5;
                n = str2num(app.ynEditField.Value);
                y(n) = str2num(init);
                f= str2func(str);
                
                for i= 1:(length(t)-1)
                    y(i+1)=y(i)+h*f(t(i),y(i));
                end
                
                plot(app.UIAxes_diff,t,y)
            end 
        end

        % Button pushed function: ClearButton1
        function ClearButton1Pushed(app, event)
            cla(app.UIAxes_diff);
            
        end

        % Value changed function: SolutionMethodDropDown
        function SolutionMethodDropDownValueChanged(app, event)
            cla(app.UIAxes_diff);
            title(app.UIAxes_diff, app.SolutionMethodDropDown.Value);
        end

        % Button pushed function: DifferentiateButton
        function DifferentiateButtonPushed(app, event)
                        h=0.01;
            x=-2*pi:h:2*pi;
            
            y= inline (app.Equat.Value,'x');
            if (app.DD.Value=='FD')
                
            for i=1:length(x)-1
            dy(i)=(y(x(i+1))-y(x(i)))/h;
            end
            xn=x(1:length(x)-1);
            title(app.UIAxes,'y vs x')
            xlabel(app.UIAxes,'x')
            ylabel(app.UIAxes,'y')
            plot(app.UIAxes,x,y(x))
            
            title(app.UIAxes2,'dy vs xn')
            xlabel(app.UIAxes2,'xn')
            ylabel(app.UIAxes2,'dy')
            plot(app.UIAxes2,xn,dy)
           
            elseif (app.DD.Value=='BD')
                for i=2:length(x)
                    dy(i-1)=(y(x(i))-y(x(i-1)))/h;
                end
                xn=x(2:length(x));
                title(app.UIAxes,'y vs x')
                xlabel(app.UIAxes,'x')
                ylabel(app.UIAxes,'y')
                plot(app.UIAxes,x,y(x))
            
                title(app.UIAxes2,'dy vs xn')
                xlabel(app.UIAxes2,'xn')
                ylabel(app.UIAxes2,'dy')
                plot(app.UIAxes2,xn,dy)
             
            else
                for i=2:length(x)-1
                    dy(i-1)=(y(x(i))-y(x(i-1)))/(2*h);
                end
                xn=x(2:length(x)-1);
                title(app.UIAxes,'y vs x')
                xlabel(app.UIAxes,'x')
                ylabel(app.UIAxes,'y')
                plot(app.UIAxes,x,y(x))
            
                title(app.UIAxes2,'dy vs xn')
                xlabel(app.UIAxes2,'xn')
                ylabel(app.UIAxes2,'dy')
                plot(app.UIAxes2,xn,dy)
            end
        
            
        end

        % Value changed function: DD
        function DDValueChanged(app, event)
            cla(app.UIAxes);
            title(app.UIAxes, app.DD.Value);
            
            cla(app.UIAxes2);
            title(app.UIAxes2, app.DD.Value);
            
        end

        % Button pushed function: ClearButton2
        function ClearButton2Pushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: ClearButton3
        function ClearButton3Pushed(app, event)
            cla(app.UIAxes2);
        end

        % Button pushed function: SimulinkButton
        function SimulinkButtonPushed(app, event)
            simulink
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
                        cla(app.UIAxes_diff);
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure = uifigure;
            app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure.Name = 'Differentiator and Ordinary Differential Equation (ODE) Solver';
            setAutoResize(app, app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, true)
            app.UIAxes_diff = uiaxes(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            title(app.UIAxes_diff, 'Title');
            xlabel(app.UIAxes_diff, 'X');
            ylabel(app.UIAxes_diff, 'Y');
            app.DyEditFieldLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.DyEditFieldLabel.Text = 'Dy =';
            app.DyEditField = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.yEditFieldLabel.Text = 'y(';
            app.ynEditField = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.SolutionMethodDropDownLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.SolutionMethodDropDownLabel.Text = 'Solution Method';
            app.SolutionMethodDropDown = uidropdown(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.SolutionMethodDropDown.Items = {'Explicit', 'Eulers'};
            app.SolutionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SolutionMethodDropDownValueChanged, true);
            app.SolutionMethodDropDown.Value = 'Explicit';
            app.SolveODEButton = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.SolveODEButton.ButtonPushedFcn = createCallbackFcn(app, @SolveODEButtonPushed, true);
            app.SolveODEButton.Text = 'Solve ODE';
            app.ClearButton1 = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.ClearButton1.ButtonPushedFcn = createCallbackFcn(app, @ClearButton1Pushed, true);
            app.ClearButton1.Text = 'Clear';
            app.Label = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.Label.Text = ') =';
            app.InitialyValueEditField = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.InitialDyValueEditField = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.DynEditField = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.SecondOrderCheckBox = uicheckbox(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.SecondOrderCheckBox.Text = 'Second Order';
            app.Label_2 = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.Label_2.Text = ') =';
            app.DyLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.DyLabel.Text = 'Dy(';
            app.ODESOLVERLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.ODESOLVERLabel.Text = 'ODE SOLVER';
            app.UIAxes = uiaxes(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.DifferentiateButton = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.DifferentiateButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentiateButtonPushed, true);
            app.DifferentiateButton.Text = 'Differentiate';
            app.DifferentiatorLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.DifferentiatorLabel.Text = 'Differentiator';
            app.DDLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.DDLabel.Text = 'Options';
            app.DD = uidropdown(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.DD.Items = {'FD', 'BD', 'CD'};
            app.DD.ValueChangedFcn = createCallbackFcn(app, @DDValueChanged, true);
            app.DD.Value = 'FD';
            app.EquatLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.EquatLabel.Text = 'Equation';
            app.Equat = uieditfield(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'text');
            app.Example6y5D2y4yLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.Example6y5D2y4yLabel.Text = '(Example : 6*y+5, D2y+4*y )';
            app.Examplesinxx2etcLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.Examplesinxx2etcLabel.Text = '(Example: sin(x), x.^2 etc)';
            app.ClearButton2 = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.ClearButton2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton2Pushed, true);
            app.ClearButton2.Text = 'Clear';
            app.ClearButton3 = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.ClearButton3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton3Pushed, true);
            app.ClearButton3.Text = 'Clear';
            app.OpenSimulinkLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.OpenSimulinkLabel.Text = 'Open Simulink : ';
            app.SimulinkButton = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.SimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @SimulinkButtonPushed, true);
            app.SimulinkButton.Text = 'Simulink';
            app.Label_3 = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.Label_3.Text = {'Full Form : FD= Forward Differentiation'; '                    BD= Backward Differentiation'; '                    CD= central Differentiation'};
            app.ClearAllButton = uibutton(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.CheckitbeforeenteringLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.CheckitbeforeenteringLabel.Text = '(Check it before entering ';
            app.secondorderequationLabel = uilabel(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure);
            app.secondorderequationLabel.Text = 'second order equation)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Differentiator_And_ODE_Solver()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DifferentiatorandOrdinaryDifferentialEquationODESolverUIFigure)
        end
    end
end